classdef DREEM_ReClassify_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        SETD                  matlab.ui.container.Menu
        EXITMenu              matlab.ui.container.Menu
        TabGroup              matlab.ui.container.TabGroup
        SleepScore            matlab.ui.container.Tab
        NEXTSUBJECTButton     matlab.ui.control.Button
        selectSub             matlab.ui.control.Button
        MicrovoltsPanel       matlab.ui.container.Panel
        mv1                   matlab.ui.control.Spinner
        mv2                   matlab.ui.control.Spinner
        mv3                   matlab.ui.control.Spinner
        mv4                   matlab.ui.control.Spinner
        mv5                   matlab.ui.control.Spinner
        SAVEDATA              matlab.ui.control.Button
        useKEYS               matlab.ui.control.Switch
        USEKEYSSwitchLabel    matlab.ui.control.Label
        stScore               matlab.ui.control.Button
        EEGElectrodesPanel    matlab.ui.container.Panel
        eeg5dd                matlab.ui.control.DropDown
        eeg2dd                matlab.ui.control.DropDown
        eeg4dd                matlab.ui.control.DropDown
        eeg3dd                matlab.ui.control.DropDown
        eeg1dd                matlab.ui.control.DropDown
        Electrophysiology     matlab.ui.container.Panel
        GridLayout            matlab.ui.container.GridLayout
        EEG5                  matlab.ui.control.UIAxes
        EEG4                  matlab.ui.control.UIAxes
        EEG3                  matlab.ui.control.UIAxes
        EEG2                  matlab.ui.control.UIAxes
        EEG1                  matlab.ui.control.UIAxes
        EEG_extra             matlab.ui.container.Panel
        notesN1               matlab.ui.control.TextArea
        NotesTextAreaLabel    matlab.ui.control.Label
        SCORINGPanel          matlab.ui.container.Panel
        perReject             matlab.ui.control.NumericEditField
        RejectLabel           matlab.ui.control.Label
        numReject             matlab.ui.control.NumericEditField
        RejectEditFieldLabel  matlab.ui.control.Label
        FIRSTEpochButton      matlab.ui.control.Button
        LASTEpochButton       matlab.ui.control.Button
        totEp                 matlab.ui.control.Label
        rejectButton          matlab.ui.control.Button
        EPOCHSpin             matlab.ui.control.Spinner
        EPOCHSpinnerLabel     matlab.ui.control.Label
        epochF                matlab.ui.control.Button
        epochB                matlab.ui.control.Button
        HYPg                  matlab.ui.control.UIAxes
    end

    %%%% WORKING ON
    % e. Add slider to top of hypnogram - CONSIDER for later version
    % 1. Check Notes added [end and start]
    % 2. Check Video Stop
    % 3.
    % 4. Check Save
    % 5. Check Load
    % 6. Update appropriate window size

    properties (Access = private)
        inDlcs % Internal directory locations
        subSel % Selected subject to work on
        subData % Primary sleep data
        dataParams % structure to characterize data
        curEpoch % Current Epoch
        zoomNOW
        zoomINDex
        EEGbutIDS
        curVIDdat
        CURframe
        ScoreID
        frameIND
        dataEDF
        annotations
        epochBLOCKs
        channelNames
        chanFreqs
        allINDS
        tmpScore
        tmpFscores
        rawHpyTab
        subject2USE
        rejectIND
        curSelName


    end

    methods (Access = private)

        function updateParams(app , stage)

            % Determine how to update parameters
            switch stage
                % Initialize parameters - ADD stuff to table if needed
                case 1
                    % Number of 30 second epochs
                    app.dataParams.epochS = app.epochBLOCKs;
                    % Set up Hypnogram plot
                    app.HYPg.XLim = [1, app.dataParams.epochS];
                    % Number of EEG electrodes
                    app.dataParams.EEGnum = length(app.channelNames);
                    app.dataParams.EEGid = app.channelNames;
                    % ID of EEG electrodes
                    eegID = transpose(app.dataParams.EEGid);
                    eegIND = transpose(1:app.dataParams.EEGnum);
                    app.dataParams.EEGtab = table(eegIND, eegID);

                    % will need to load in separate file or add to EDF
                    app.dataParams.SleepStg = table2cell(app.tmpFscores);
 
                    app.dataParams.sampPts = app.chanFreqs(1);

                    % CHECK if notes column is avialable
                    if ~ismember('notes',app.dataEDF.Properties.VariableNames)
                        app.dataEDF.notes = repmat({''},height(app.dataEDF),1);
                        app.dataParams.notes = app.dataEDF.notes;
                    else
                        app.dataParams.notes = app.dataEDF.notes;
                    end

            end
        end

        function updateEEGplot(app , stage)

            switch stage
                case 1 % Initialize
                    app.curEpoch = 1; % Reset current epoch to 1
                    app.EPOCHSpin.Value = app.curEpoch;
                    for st1 = 1:5  % NUMBER
                        % Determine how to select the correct column vector
                        % based on the EEG electrode from dropdown

                        getEEGbuttons(app)

                        colID1 = app.EEGbutIDS(st1);
                        colID2 = app.dataParams.EEGtab.eegIND(ismember(app.dataParams.EEGtab.eegID,colID1));

                        edfINDEX1 = app.allINDS(app.curEpoch,1);
                        edfINDEX2 = app.allINDS(app.curEpoch,2);
                        edftmpDat = app.dataEDF{edfINDEX1:edfINDEX2,colID2};
                        edftmpDat2 = reshape([edftmpDat{:}],numel([edftmpDat{:}]),1);

                        app.dataParams.sampPts = app.chanFreqs(colID2);

                        vecCell = edftmpDat2;
                        eegVec = vecCell;

                        % Fix time vector
                        timeVEC = round(linspace(0,30,app.dataParams.sampPts*30),2);

                        ylabel(app.(['EEG',num2str(st1)]),colID1{1},'Interpreter','none')
                        smEegVec2 = eegVec;
                        plot(app.(['EEG',num2str(st1)]),timeVEC,smEegVec2)
                        [yTmax , yTmin] = getYlimVs(app,smEegVec2,st1);
                        yTmax = yTmax*app.(['mv',num2str(st1)]).Value;
                        yTmin = yTmin*app.(['mv',num2str(st1)]).Value;
                        ylim(app.(['EEG',num2str(st1)]),[yTmin , yTmax])

                        % NEW SUBtitle
                        if isempty(app.dataParams.SleepStg{app.curEpoch})
                            curSlepStg = 'NONE';
                        else
                            curSlepStg = app.dataParams.SleepStg{app.curEpoch};
                        end

                        epochNN = num2str(app.curEpoch);
                        %                             tIME = datestr(timeofday(app.dataParams.Atime(app.curEpoch)));
                        txt = ['Stage:  ',curSlepStg,'         EPOCH: ',epochNN];

                        if st1 == 1
                            subtitle(app.(['EEG',num2str(st1)]),txt,'FontSize',16)
                            app.(['EEG',num2str(st1)]).TitleHorizontalAlignment = 'left';
                        end

                    end


                case 2 % Move back or forward
                    for st2 = 1:5 % NUMBER of EEG PLOTS - WAS app.dataParams.EEGnum %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        getEEGbuttons(app)

                        colID1 = app.EEGbutIDS(st2);
                        colID2 = app.dataParams.EEGtab.eegIND(ismember(app.dataParams.EEGtab.eegID,colID1));

                        edfINDEX1 = app.allINDS(app.curEpoch,1);
                        edfINDEX2 = app.allINDS(app.curEpoch,2);
                        edftmpDat = app.dataEDF{edfINDEX1:edfINDEX2,colID2};
                        edftmpDat2 = reshape([edftmpDat{:}],numel([edftmpDat{:}]),1);
                        % Fix time vector
                        app.dataParams.sampPts = app.chanFreqs(colID2);

                        vecCell = edftmpDat2;
                        eegVec = vecCell;

                        % Fix time vector
                        timeVEC = round(linspace(0,30,app.dataParams.sampPts*30),2);

                        ylabel(app.(['EEG',num2str(st2)]),colID1{1})
                        smEegVec2 = eegVec;
                        % HYPNOPLOT

                        if  app.rejectIND(app.curEpoch)
                            useColor = [1 0 0];
                            linWid = 2;
                        else
                            useColor = ([0, 119, 182])/255;
                            linWid = 1;
                        end

                        plot(app.(['EEG',num2str(st2)]),timeVEC, smEegVec2,...
                            "Color",useColor,"LineWidth",linWid)
                        [yTmax , yTmin] = getYlimVs(app,smEegVec2,st2);

                        yTmax = yTmax*app.(['mv',num2str(st2)]).Value;
                        yTmin = yTmin*app.(['mv',num2str(st2)]).Value;
                        ylim(app.(['EEG',num2str(st2)]),[yTmin , yTmax])

                        % NEW SUBtitle
                        if isempty(app.dataParams.SleepStg{app.curEpoch})
                            curSlepStg = 'NONE';
                        else
                            curSlepStg = app.dataParams.SleepStg{app.curEpoch};
                        end

                        epochNN = num2str(app.curEpoch);
                        txt = ['Stage:  ',curSlepStg,'         EPOCH: ',epochNN];

                        if st2 == 1
                            subtitle(app.(['EEG',num2str(st2)]),txt,'FontSize',16)
                            app.(['EEG',num2str(st2)]).TitleHorizontalAlignment = 'left';
                        end
                    end
            end

        end

        function [yMaxR , yMinR] = getYlimVs(app,vecOfint,si)

            yMax = max(vecOfint);
            yMin = abs(min(vecOfint));
            if yMax > yMin
                yMaxR = yMax;
                yMinR = yMax*-1;
                ylim(app.(['EEG',num2str(si)]),[-1*yMax , yMax])
            else
                yMaxR = yMin;
                yMinR = yMin*-1;
                ylim(app.(['EEG',num2str(si)]),[-1*yMin , yMin])
            end

        end


        function updateButtonAct(app)

            % ADD a EPOCH NUMBER CHECK
            if app.curEpoch > app.dataParams.epochS
                uialert(app.UIFigure,'SCORED LAST EPOCH!','SCORING complete!','Icon','info');
                return
            end

            % Update Sleep Stage Cell Array
            app.rejectIND(app.curEpoch) = 1;
            % Update Hypnogram plot
            updateHYPlot(app)
            % Advance Epoch
            app.curEpoch = app.curEpoch + 1;

            % ADD a EPOCH NUMBER CHECK
            if app.curEpoch > app.dataParams.epochS
                uialert(app.UIFigure,'SCORED LAST EPOCH!','SCORING complete!','Icon','info');
                return
            end

            app.EPOCHSpin.Value = app.curEpoch;
            % Update EEG plot
            updateEEGplot(app,2)
            % Update EEG extra
            updateEEGextra(app,1)

            calcReject(app)

        end

        function updateHYPlot(app)

            stageS = {'N3','N2','N1','REM','Awake'};
            cla(app.HYPg)
            tmpHyp = nan(app.dataParams.epochS,1);
            baseHYPcols = nan(app.dataParams.epochS,3);
            % REM = 4 = RED
            for i = 1:5
                tmpHyp(ismember(app.dataParams.SleepStg,stageS{i})) = i;

                if i == 4
                    baseHYPcols(ismember(app.dataParams.SleepStg,stageS{i}),:) =...
                        repmat([1,0,0],sum(ismember(app.dataParams.SleepStg,stageS{i})),1);
                else
                    baseHYPcols(ismember(app.dataParams.SleepStg,stageS{i}),:) =...
                        repmat([0,0,0],sum(ismember(app.dataParams.SleepStg,stageS{i})),1);
                end

            end
            xPoints = transpose(1:app.dataParams.epochS);
            hold(app.HYPg,'on')
            scatter(app.HYPg,xPoints,tmpHyp,5,baseHYPcols,"filled")
            hold(app.HYPg,'off')
            line(app.HYPg,[app.curEpoch app.curEpoch], [0 6], 'Color','g','LineStyle','--')

            % PLOT REJECT LINES
            if ~isempty(find(app.rejectIND, 1))
                xline(app.HYPg,find(app.rejectIND),'k--')
            end

        end

        function updateEEGextra(app , FoB)

            if FoB
                lastEpoch = app.curEpoch - 1;
            else
                lastEpoch = app.curEpoch + 1;
            end
            % Save current state
            app.dataParams.notes(lastEpoch) = app.notesN1.Value;
            % Show current state
            app.notesN1.Value = app.dataParams.notes(app.curEpoch);
        end


        function getEEGbuttons(app)

            app.EEGbutIDS = cell(12,1);

            for ei = 1:5
                app.EEGbutIDS{ei} = app.(['eeg',num2str(ei),'dd']).Value;
            end

        end


        function checkREJECT(app)
           
            if app.rejectIND(app.curEpoch)
                app.rejectButton.Enable = "off";

            else
                app.rejectButton.Enable = "on";
            end

        end
        
        function calcReject(app)

            curRejVec = app.rejectIND;
            numEpochs = numel(curRejVec);
            totReject = sum(curRejVec);
            fracReject = round((totReject/numEpochs)*100,2);

            app.numReject.Value = totReject;
            app.perReject.Value = fracReject;

            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: SETD
        function SETDMenuSelected(app, event)
            % Disenable Set directory menu
            app.SETD.Enable = 'off';
            txtFold = uigetdir();
            % locate hard coded DS directory
            app.inDlcs.dsLOC1 = txtFold;
            % Navigate to DS and populate List
            cd(app.inDlcs.dsLOC1)
            % Idenitify available recordings
            subDir = dir('*.edf');
            subDirn = {subDir.name};
            app.subject2USE = subDirn;
            app.selectSub.Enable = "on";


        end

        % Button pushed function: selectSub
        function selectSubButtonPushed(app, event)

            % ADD progress bar
            subSelprog = uiprogressdlg(app.UIFigure,'Title','LOADING SUBJECT',...
                'Indeterminate','on');
            drawnow

            seleSubID = uigetfile('*.edf');
            edfName = extractBefore(seleSubID,'.');
            app.curSelName = edfName;

            % app.inDlcs.dsLOC1

            [app.dataEDF,app.annotations] = edfread(seleSubID);
            app.epochBLOCKs = round(height(app.dataEDF)/30)-1;
            app.channelNames = app.dataEDF.Properties.VariableNames;
            sampFreqs = zeros(1,length(app.channelNames));
            for si = 1:length(app.channelNames)
                sampFreqs(si) = length(app.dataEDF.(app.channelNames{si}){1});
            end
            app.chanFreqs = sampFreqs;
            blockend = height(app.dataEDF);

            startINDS = transpose(1:30:blockend);
            endINDS = [startINDS(2:end) - 1; blockend];
            app.allINDS = [startINDS , endINDS];

            hypnoTextN = [app.curSelName '_hypnogram.txt'];

            hypnoTab = readtable(hypnoTextN);

            hypnoTab2 = hypnoTab(1:app.epochBLOCKs,:);

            app.rawHpyTab = hypnoTab2;

            newNAMES = {'Awake','N1','N2','N3','REM','Movement'};
            oldNAMES = {'SLEEP-S0','SLEEP-S1','SLEEP-S2','SLEEP-S3',...
                'SLEEP-REM','SLEEP-MT'};
            sleepSTAGES = hypnoTab2.SleepStage;
            for oi = 1:length(oldNAMES)
                sleepSTAGES(matches(sleepSTAGES,oldNAMES{oi})) = newNAMES(oi);
            end

            sleepStagTab = table(sleepSTAGES,'VariableNames',{'sleepstage'});

            app.tmpScore = 1;
            app.tmpFscores = sleepStagTab;
            updateParams(app , 1)

            app.EEGElectrodesPanel.Enable = 'on';
            % Update EEG Panel buttons
            for ebi = 1:5
                app.(['eeg',num2str(ebi),'dd']).Enable = 'on';
                app.(['eeg',num2str(ebi),'dd']).Items = table2array(app.dataParams.EEGtab(:,2));
                app.(['eeg',num2str(ebi),'dd']).Value = app.dataParams.EEGtab{ebi + 1,2};
            end

            % check for reject list
            matRejctF = [app.curSelName '_reject.mat'];
            matCHECK = dir('*.mat');
            matCHECKd = {matCHECK.name};
            matCHECKpr = matches(matRejctF,matCHECKd);
            if matCHECKpr
                load(matRejctF,'rejectINDEX')
                app.rejectIND = rejectINDEX.RejectIndex;
            else
                app.rejectIND = zeros(size(sleepSTAGES));
            end

            % Set button list
            getEEGbuttons(app)
            % Turn on New Subject

            % Turn on Start Score button
            app.stScore.Enable = 'on';
            % Current Epoch start
            app.curEpoch = 1;

            % CLOSE PROGRESS
            close(subSelprog)

            app.selectSub.Enable = "off";

            % Activate Next subject button
            app.NEXTSUBJECTButton.Enable = 'off';

        end

        % Menu selected function: EXITMenu
        function EXITMenuSelected(app, event)

            selection = uiconfirm(app.UIFigure,'Close Program?','Confirm Close',...
                'Icon','warning');
            switch selection
                case 'OK'
                    delete(app)
                case 'Cancel'
                    return
            end

        end

        % Button pushed function: epochB
        function epochBButtonPushed(app, event)
            app.curEpoch = app.curEpoch - 1;
            if app.curEpoch < 1
                app.curEpoch = 1;
                return
            else
                app.EPOCHSpin.Value = app.curEpoch;

                checkREJECT(app)

                updateEEGplot(app, 2)

                % Update Hypnogram
                updateHYPlot(app)

                % Update EEG extra data
                updateEEGextra(app,0)
            end
        end

        % Button pushed function: epochF
        function epochFButtonPushed(app, event)
            app.curEpoch = app.curEpoch + 1;
            if app.curEpoch > app.dataParams.epochS
                app.curEpoch = app.dataParams.epochS;
                return
            else
                app.EPOCHSpin.Value = app.curEpoch;
                updateEEGplot(app, 2)

                % Update Hypnogram
                updateHYPlot(app)

                % Update EEG extra data
                updateEEGextra(app,1)
            end
        end

        % Value changed function: EPOCHSpin
        function EPOCHSpinValueChanged(app, event)
            lastEpoch = app.curEpoch;
            app.curEpoch = app.EPOCHSpin.Value;
            if app.curEpoch >= app.dataParams.epochS
                app.curEpoch = app.dataParams.epochS;
                app.EPOCHSpin.Value = app.curEpoch;
                updateEEGplot(app, 2)
                updateHYPlot(app)
                return
            elseif app.curEpoch <= 1
                app.curEpoch = 1;
                app.EPOCHSpin.Value = app.curEpoch;
                updateEEGplot(app, 2)
                updateHYPlot(app)
                return
            else
                app.EPOCHSpin.Value = app.curEpoch;

                updateEEGplot(app, 2)

                % Update Hypnogram
                updateHYPlot(app)

                if lastEpoch < app.curEpoch
                    updateEEGextra(app,2)
                else
                    updateEEGextra(app,1)
                end
            end

        end

        % Button pushed function: rejectButton
        function rejectButtonButtonPushed(app, event)
            updateButtonAct(app)
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;

            if strcmp(app.useKEYS.Value,'Off')
                return
            else

                switch key
                    case '0'
                        if app.zoomINDex == 1
                            updateButtonAct(app)
                        else
                            return
                        end
                    case 'leftarrow'
                        app.curEpoch = app.curEpoch - 1;
                        if app.curEpoch < 1
                            app.curEpoch = 1;
                            return
                        else
                            app.EPOCHSpin.Value = app.curEpoch;
                            updateEEGplot(app, 2)
                            updateHYPlot(app)
                        end
                    case 'rightarrow'

                        app.curEpoch = app.curEpoch + 1;
                        if app.curEpoch > app.dataParams.epochS
                            app.curEpoch = app.dataParams.epochS;
                            return
                        else
                            app.EPOCHSpin.Value = app.curEpoch;
                            updateEEGplot(app, 2)
                            updateHYPlot(app)
                        end

                    case 'r'
                        app.curEpoch = app.curEpoch + 1;
                        if app.curEpoch > app.dataParams.epochS
                            app.curEpoch = app.dataParams.epochS;
                            return
                        else
                            app.EPOCHSpin.Value = app.curEpoch;
                            updateButtonAct(app)
                        end

                    otherwise
                        return
                end
            end
        end

        % Button pushed function: stScore
        function stScoreButtonPushed(app, event)
            % Update EEG Panel buttons
            for ebi = 1:5 % NUMBER of EEG PLOTS - WAS app.dataParams.EEGnum  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                app.(['EEG',num2str(ebi)]).Visible = 'on';
            end

            % Plot First Epoch of Data
            updateEEGplot(app, 1)

            % Open Panels
            app.SCORINGPanel.Enable = 'on';
            app.EEG_extra.Enable = 'on';

            % Turn off start scoring button
            app.stScore.Enable = 'off';

            % If any hypodata is present - added 2/9/2021
            updateHYPlot(app)

            % Update EEG extra data If any hypodata is present - added 2/9/2021
            updateEEGextra(app,0)

            % Activate Next subject button
            app.NEXTSUBJECTButton.Enable = 'on';
            app.SCORINGPanel.Enable = 'on';


        end

        % Button pushed function: SAVEDATA
        function SAVEDATAButtonPushed(app, event)

            saveDLG = uiprogressdlg(app.UIFigure,'Title','SAVING DATA...Please Wait!',...
                'Indeterminate','on');
            drawnow

            % Get Folder Loc
            cd(app.inDlcs.dsLOC1)
            % Save out data
            stageVEC = app.dataParams.SleepStg; % has sleep stages
            epochReject = app.rejectIND;
            rejectINDEX = table(stageVEC,epochReject,'VariableNames',{'Hypnogram','RejectIndex'});
            % Score NAME -
            rjectFname = [app.curSelName '_reject.mat'];

            save(rjectFname,"rejectINDEX");

            % Message that data has been saved
            close(saveDLG)
            uialert(app.UIFigure,'Data SAVED!','Save File Confirm','Icon',"success");

        end

        % Button pushed function: FIRSTEpochButton
        function FIRSTEpochButtonPushed(app, event)

            app.curEpoch = 1;
            app.EPOCHSpin.Value = 1;
            % Plot First Epoch of Data
            updateEEGplot(app, 2)
            % If any hypodata is present - added 2/9/2021
            updateHYPlot(app)
            % Update EEG extra data If any hypodata is present - added 2/9/2021
            updateEEGextra(app,0)

        end

        % Button pushed function: LASTEpochButton
        function LASTEpochButtonPushed(app, event)

            app.curEpoch = app.epochBLOCKs;
            app.EPOCHSpin.Value = app.epochBLOCKs;
            % Plot First Epoch of Data
            updateEEGplot(app, 2)
            % If any hypodata is present - added 2/9/2021
            updateHYPlot(app)
            % Update EEG extra data If any hypodata is present - added 2/9/2021
            updateEEGextra(app,0)

        end

        % Button pushed function: NEXTSUBJECTButton
        function NEXTSUBJECTButtonPushed(app, event)
            
            % Activate Select Subject button
            app.selectSub.Enable = 'on';

            % Deactivate User panel
            app.SCORINGPanel.Enable = 'off';


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.SETD = uimenu(app.UIFigure);
            app.SETD.MenuSelectedFcn = createCallbackFcn(app, @SETDMenuSelected, true);
            app.SETD.Text = 'SET Directory';
            app.EXITMenu = uimenu(app.UIFigure);
            app.EXITMenu.MenuSelectedFcn = createCallbackFcn(app, @EXITMenuSelected, true);
            app.EXITMenu.Text = 'EXIT';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SleepScore = uitab(app.TabGroup);
            app.SleepScore.Title = 'SleepScore';
            app.HYPg = uiaxes(app.SleepScore);
            app.HYPg.YLim = [0 6];
            app.HYPg.XTick = [];
            app.HYPg.XTickLabel = '';
            app.HYPg.YTick = [1 2 3 4 5];
            app.HYPg.YTickLabel = {'N3'; 'N2'; 'N1'; 'R'; 'W'};
            app.SCORINGPanel = uipanel(app.SleepScore);
            app.SCORINGPanel.Enable = 'off';
            app.SCORINGPanel.Title = 'SCORING Panel';
            app.epochB = uibutton(app.SCORINGPanel, 'push');
            app.epochB.ButtonPushedFcn = createCallbackFcn(app, @epochBButtonPushed, true);
            app.epochB.Text = '<- Epoch';
            app.epochF = uibutton(app.SCORINGPanel, 'push');
            app.epochF.ButtonPushedFcn = createCallbackFcn(app, @epochFButtonPushed, true);
            app.epochF.Text = 'Epoch ->';
            app.EPOCHSpinnerLabel = uilabel(app.SCORINGPanel);
            app.EPOCHSpinnerLabel.Text = 'EPOCH';
            app.EPOCHSpin = uispinner(app.SCORINGPanel);
            app.EPOCHSpin.ValueChangedFcn = createCallbackFcn(app, @EPOCHSpinValueChanged, true);
            app.EPOCHSpin.Value = 1;
            app.rejectButton = uibutton(app.SCORINGPanel, 'push');
            app.rejectButton.ButtonPushedFcn = createCallbackFcn(app, @rejectButtonButtonPushed, true);
            app.rejectButton.Text = 'REJECT';
            app.totEp = uilabel(app.SCORINGPanel);
            app.totEp.Text = 'TOTAL';
            app.LASTEpochButton = uibutton(app.SCORINGPanel, 'push');
            app.LASTEpochButton.ButtonPushedFcn = createCallbackFcn(app, @LASTEpochButtonPushed, true);
            app.LASTEpochButton.Text = 'LAST Epoch';
            app.FIRSTEpochButton = uibutton(app.SCORINGPanel, 'push');
            app.FIRSTEpochButton.ButtonPushedFcn = createCallbackFcn(app, @FIRSTEpochButtonPushed, true);
            app.FIRSTEpochButton.Text = 'FIRST Epoch';
            app.RejectEditFieldLabel = uilabel(app.SCORINGPanel);
            app.RejectEditFieldLabel.Text = '# Reject';
            app.numReject = uieditfield(app.SCORINGPanel, 'numeric');
            app.numReject.Editable = 'off';
            app.RejectLabel = uilabel(app.SCORINGPanel);
            app.RejectLabel.Text = '% Reject';
            app.perReject = uieditfield(app.SCORINGPanel, 'numeric');
            app.perReject.Editable = 'off';
            app.EEG_extra = uipanel(app.SleepScore);
            app.EEG_extra.Enable = 'off';
            app.EEG_extra.Title = 'Additional information';
            app.NotesTextAreaLabel = uilabel(app.EEG_extra);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.notesN1 = uitextarea(app.EEG_extra);
            app.Electrophysiology = uipanel(app.SleepScore);
            app.GridLayout = uigridlayout(app.Electrophysiology);
            app.EEG1 = uiaxes(app.GridLayout);
            app.EEG1.XTick = [];
            app.EEG1.YTick = [];
            app.EEG2 = uiaxes(app.GridLayout);
            app.EEG2.XTick = [];
            app.EEG2.YTick = [];
            app.EEG3 = uiaxes(app.GridLayout);
            app.EEG3.XTick = [];
            app.EEG3.YTick = [];
            app.EEG4 = uiaxes(app.GridLayout);
            app.EEG4.XTick = [];
            app.EEG4.YTick = [];
            app.EEG5 = uiaxes(app.GridLayout);
            app.EEG5.XTick = [];
            app.EEG5.YTick = [];
            app.EEGElectrodesPanel = uipanel(app.SleepScore);
            app.EEGElectrodesPanel.Title = 'EEG Electrodes';
            app.eeg1dd = uidropdown(app.EEGElectrodesPanel);
            app.eeg1dd.Items = {'EEG1', 'EEG2', 'EEG3', 'EEG4', 'EEG5', 'EEG6', 'EEG7', 'EEG8', 'EEG9', 'EEG10', 'EEG11', 'EEG12'};
            app.eeg1dd.Value = 'EEG1';
            app.eeg3dd = uidropdown(app.EEGElectrodesPanel);
            app.eeg3dd.Items = {'EEG1', 'EEG2', 'EEG3', 'EEG4', 'EEG5', 'EEG6', 'EEG7', 'EEG8', 'EEG9', 'EEG10', 'EEG11', 'EEG12'};
            app.eeg3dd.Value = 'EEG3';
            app.eeg4dd = uidropdown(app.EEGElectrodesPanel);
            app.eeg4dd.Items = {'EEG1', 'EEG2', 'EEG3', 'EEG4', 'EEG5', 'EEG6', 'EEG7', 'EEG8', 'EEG9', 'EEG10', 'EEG11', 'EEG12'};
            app.eeg4dd.Value = 'EEG4';
            app.eeg2dd = uidropdown(app.EEGElectrodesPanel);
            app.eeg2dd.Items = {'EEG1', 'EEG2', 'EEG3', 'EEG4', 'EEG5', 'EEG6', 'EEG7', 'EEG8', 'EEG9', 'EEG10', 'EEG11', 'EEG12'};
            app.eeg2dd.Value = 'EEG2';
            app.eeg5dd = uidropdown(app.EEGElectrodesPanel);
            app.eeg5dd.Items = {'EEG1', 'EEG2', 'EEG3', 'EEG4', 'EEG5', 'EEG6', 'EEG7', 'EEG8', 'EEG9', 'EEG10', 'EEG11', 'EEG12'};
            app.eeg5dd.Value = 'EEG5';
            app.stScore = uibutton(app.SleepScore, 'push');
            app.stScore.ButtonPushedFcn = createCallbackFcn(app, @stScoreButtonPushed, true);
            app.stScore.Enable = 'off';
            app.stScore.Text = 'Start Scoring';
            app.USEKEYSSwitchLabel = uilabel(app.SleepScore);
            app.USEKEYSSwitchLabel.Text = 'USE KEYS';
            app.useKEYS = uiswitch(app.SleepScore, 'slider');
            app.SAVEDATA = uibutton(app.SleepScore, 'push');
            app.SAVEDATA.ButtonPushedFcn = createCallbackFcn(app, @SAVEDATAButtonPushed, true);
            app.SAVEDATA.Text = 'SAVE DATA';
            app.MicrovoltsPanel = uipanel(app.SleepScore);
            app.MicrovoltsPanel.Title = 'Microvolts';
            app.mv5 = uispinner(app.MicrovoltsPanel);
            app.mv5.Limits = [1 4];
            app.mv5.Value = 1;
            app.mv4 = uispinner(app.MicrovoltsPanel);
            app.mv4.Limits = [1 4];
            app.mv4.Value = 1;
            app.mv3 = uispinner(app.MicrovoltsPanel);
            app.mv3.Limits = [1 4];
            app.mv3.Value = 1;
            app.mv2 = uispinner(app.MicrovoltsPanel);
            app.mv2.Limits = [1 4];
            app.mv2.Value = 1;
            app.mv1 = uispinner(app.MicrovoltsPanel);
            app.mv1.Limits = [1 4];
            app.mv1.Value = 1;
            app.selectSub = uibutton(app.SleepScore, 'push');
            app.selectSub.ButtonPushedFcn = createCallbackFcn(app, @selectSubButtonPushed, true);
            app.selectSub.Enable = 'off';
            app.selectSub.Text = 'Select Subject';
            app.NEXTSUBJECTButton = uibutton(app.SleepScore, 'push');
            app.NEXTSUBJECTButton.ButtonPushedFcn = createCallbackFcn(app, @NEXTSUBJECTButtonPushed, true);
            app.NEXTSUBJECTButton.Enable = 'off';
            app.NEXTSUBJECTButton.Text = 'NEXT SUBJECT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DREEM_ReClassify_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end